classdef BH_vs_WBK_Examiner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFPotentialCalculatorUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        gamma_WBK_value                matlab.ui.control.Label
        gamma_WBK_Label                matlab.ui.control.Label
        PlotDropDown                   matlab.ui.control.DropDown
        PlotDropDownLabel              matlab.ui.control.Label
        C_value                        matlab.ui.control.Label
        B_value                        matlab.ui.control.Label
        alpha_value                    matlab.ui.control.Label
        B_Label                        matlab.ui.control.Label
        C_Label                        matlab.ui.control.Label
        alpha_Label                    matlab.ui.control.Label
        gamma_Slider                   matlab.ui.control.Slider
        gamma_BHLabel                  matlab.ui.control.Label
        r_0_Slider                     matlab.ui.control.Slider
        r_0Label                       matlab.ui.control.Label
        epsilon_Slider                 matlab.ui.control.Slider
        log_10epsilonLabel             matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Interaction_Plot               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Qmin = 38.8722633361816; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeData(app)
            UpdateAppData(app, [])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TFPotentialCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {470, 470};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {313, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: PlotDropDown, epsilon_Slider, 
        % gamma_Slider, r_0_Slider
        function UpdateAppData(app, event)
            
            PlotType = app.PlotDropDown.Value;
            epsilon_inp = 10^(app.epsilon_Slider.Value);
            r0 = app.r_0_Slider.Value;
            gamma = app.gamma_Slider.Value;
            if gamma < 6
                epsilon_BH = -epsilon_inp;
            else
                epsilon_BH = epsilon_inp;
            end
            
            k = 1/(gamma - 6);
            
            % Calculate Tight form Parameters
            alpha = gamma/r0;           % Exp Steepness parameter
            B = 6*epsilon_BH*k*exp(gamma); % Repulsive prefactor
            C = epsilon_BH*gamma*k*(r0^6); % 1/r6 dispersion
            
            % Update text values
            app.alpha_value.Text = num2str(alpha,'%15.9f');
            app.B_value.Text = num2str(B,'%15.9f');
            app.C_value.Text = num2str(C,'%15.9f');
            
            if abs(gamma - 6) < sqrt(eps)
                return
            elseif abs(r0) < sqrt(eps)
                return
            end
            
            f_BH = @(r) 6*epsilon_BH*k*exp(gamma*(1 - r./r0)) - epsilon_BH*gamma*k*((r0./r).^6);
            df_BH = @(r) -6*(gamma/r0)*epsilon_BH*k*exp(gamma*(1 - r./r0)) + 6*epsilon_BH*gamma*k*(r0^6)./(r.^7);
            df_BH_neg = @(r) 6*(gamma/r0)*epsilon_BH*k*exp(gamma*(1 - r./r0)) - 6*epsilon_BH*gamma*k*(r0^6)./(r.^7);
            if gamma < 7 % find the well minimum
                sigma_WBK = fzero(df_BH,r0+10);
                eps_WBK = abs(f_BH(sigma_WBK));
            else % In this domain, r0 and epsilon describe the well minima
                sigma_WBK = r0;
                eps_WBK = abs(epsilon_inp);
            end
            % find the inflection point
            r_infl = fminsearchbnd(df_BH,0.95*sigma_WBK,0,sigma_WBK);
            if r_infl < sqrt(eps)
                r_infl = sigma_WBK;
            end
            r_infl_out = fminsearchbnd(df_BH_neg,1.01*sigma_WBK,sigma_WBK,1000);
            
            % Define the WBK function with unknown gamma
            f_WBK_gam = @(r,gamma_WBK) (2*eps_WBK./(1 - (3./(gamma_WBK+3)))).*((sigma_WBK^6)./((sigma_WBK^6) + (r.^6))).*((3./(gamma_WBK+3)).*exp(gamma_WBK.*(1 - (r./sigma_WBK))) - 1);
            
            % Domain to compare BK and WBK functions
            r_check = r_infl:0.01:r_infl_out;
            
            % Define a new function that computes the squared difference between BK and WBK functions for a given input gamma
            diff_BH_WBK = @(gamma_WBK) sum((f_BH(r_check) - f_WBK_gam(r_check,gamma_WBK)).^2);
            
            % Optimize the value of WBK gamma
            gamma_WBK = fminsearchbnd(diff_BH_WBK,gamma,0,100);

            app.gamma_WBK_value.Text = num2str(gamma_WBK,'%15.9f');
            
            switch PlotType
            case 'LJ'
                f_BH_out = @(r) f_BH(r);
                f_WBK_out = @(r) f_WBK_gam(r,gamma_WBK);
                
                r_well = sigma_WBK;
                fmin = f_BH_out(r_well);
                
                % Check if r_well is larger than the x limits
                if sigma_WBK > 18.19
                    r = 0:0.001:r_well+r_well*1.1;
                    xmax = r_well+r_well*1.1;
                else
                    r = 0:0.001:20;
                    xmax = 20;
                end
                
                % Calculate potential
                f_BH = f_BH_out(r);
                f_WBK = f_WBK_out(r);
                
                % Set ylimit
                ylims = [-10 10];
            case {'Full Attractive' 'Full Repulsive'}
                q1 = 1;
                if strcmp(PlotType,'Full Attractive')
                    q2 = -1;
                else
                    q2 = 1;
                end
                Ang_per_m = 1e+10; % Ang per m
                NA = 6.0221409e23; % Molecules per mole
                e_c = 1.60217662e-19; % Elementary charge in Coulombs
                epsilon_BH_0 = (8.854187817620e-12)*1000/(Ang_per_m*NA); % Vacuum Permittivity C^2 mol kJ^-1 Ang^-1
                k_0 = 1/(4*pi*epsilon_BH_0); % Coulomb constant in kJ Ang C^-2 mol^-1
                
                f_BH_out  = @(r) f_BH(r)                + k_0*(e_c^2)*q1*q2./(r);
                f_WBK_out = @(r) f_WBK_gam(r,gamma_WBK) + k_0*(e_c^2)*q1*q2./(r);
                
                df_BH_out = @(r) df_BH(r)                - k_0*(e_c^2)*q1*q2./(r.^2);
                if gamma < 7
                    r_well = fzero(df_BH_out,r0+10);
                else
                    r_well = fzero(df_BH_out,r0*0.6);
                end
                
                fmin = f_BH_out(r_well);
                
                % Check if r_well is larger than the x limits
                if r_well > 18.19
                    r = 0:0.001:r_well+r_well*1.1;
                    xmax = r_well+r_well*1.1;
                else
                    r = 0:0.001:20;
                    xmax = 20;
                end
                
                % Calculate potential
                f_BH = f_BH_out(r);
                f_WBK = f_WBK_out(r);
                
                ylims = [-1000 1000];
            end
            
            % Update plot
            ax = app.Interaction_Plot;
            cla(ax)
            hold(ax,'on')
            yline(ax,0,':k',"LineWidth",1)
            plot(ax,r,f_BH,'-b',"LineWidth",3)
            plot(ax,r,f_WBK,'--r',"LineWidth",3)
            
            scatter(ax,r_well,fmin,50,'blue','filled','o')
            scatter(ax,r0,f_BH_out(r0),50,'blue','filled','o')
            
            ylim(ax,ylims)
            xlim(ax,[0,xmax])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFPotentialCalculatorUIFigure = uifigure('Visible', 'off');
            app.TFPotentialCalculatorUIFigure.Name = 'Exp C6 C8 Loose Form';
            app.TFPotentialCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TFPotentialCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.log_10epsilonLabel = uilabel(app.LeftPanel);
            app.log_10epsilonLabel.Interpreter = 'latex';
            app.log_10epsilonLabel.Text = '$\log_{10}(\epsilon)$';
            app.epsilon_Slider = uislider(app.LeftPanel);
            app.epsilon_Slider.Limits = [0 10];
            app.epsilon_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.epsilon_Slider.Value = 5;
            app.r_0Label = uilabel(app.LeftPanel);
            app.r_0Label.Interpreter = 'latex';
            app.r_0Label.Text = '$r_{0}$';
            app.r_0_Slider = uislider(app.LeftPanel);
            app.r_0_Slider.Limits = [0 20];
            app.r_0_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.r_0_Slider.Value = 5;
            app.gamma_BHLabel = uilabel(app.LeftPanel);
            app.gamma_BHLabel.Interpreter = 'latex';
            app.gamma_BHLabel.Text = '$\gamma_{BH}$';
            app.gamma_Slider = uislider(app.LeftPanel);
            app.gamma_Slider.Limits = [0 20];
            app.gamma_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.gamma_Slider.Value = 6;
            app.alpha_Label = uilabel(app.LeftPanel);
            app.alpha_Label.Interpreter = 'latex';
            app.alpha_Label.Text = '$\alpha$ =';
            app.C_Label = uilabel(app.LeftPanel);
            app.C_Label.Interpreter = 'latex';
            app.C_Label.Text = '$C$ =';
            app.B_Label = uilabel(app.LeftPanel);
            app.B_Label.Interpreter = 'latex';
            app.B_Label.Text = '$B$ =';
            app.alpha_value = uilabel(app.LeftPanel);
            app.alpha_value.Interpreter = 'latex';
            app.alpha_value.Text = '0';
            app.B_value = uilabel(app.LeftPanel);
            app.B_value.Interpreter = 'latex';
            app.B_value.Text = '0';
            app.C_value = uilabel(app.LeftPanel);
            app.C_value.Interpreter = 'latex';
            app.C_value.Text = '0';
            app.PlotDropDownLabel = uilabel(app.LeftPanel);
            app.PlotDropDownLabel.Text = 'Plot:';
            app.PlotDropDown = uidropdown(app.LeftPanel);
            app.PlotDropDown.Items = {'LJ', 'Full Attractive', 'Full Repulsive'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.PlotDropDown.Value = 'LJ';
            app.gamma_WBK_Label = uilabel(app.LeftPanel);
            app.gamma_WBK_Label.Interpreter = 'latex';
            app.gamma_WBK_Label.Text = '$\gamma_{WBK} =$';
            app.gamma_WBK_value = uilabel(app.LeftPanel);
            app.gamma_WBK_value.Interpreter = 'latex';
            app.gamma_WBK_value.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.Interaction_Plot = uiaxes(app.RightPanel);
            title(app.Interaction_Plot, 'Model Plot')
            xlabel(app.Interaction_Plot, 'r')
            ylabel(app.Interaction_Plot, 'U(r)')
            % Show the figure after all components are created
            app.TFPotentialCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BH_vs_WBK_Examiner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TFPotentialCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitializeData)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFPotentialCalculatorUIFigure)
        end
    end
end