classdef Exp_C6_C8_Examiner_ExtraDim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFPotentialCalculatorUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Dalpha2_over_C_value           matlab.ui.control.Label
        Dalpha2_over_C_Label           matlab.ui.control.Label
        D_over_C_value                 matlab.ui.control.Label
        D_over_C_Label                 matlab.ui.control.Label
        D_value                        matlab.ui.control.Label
        C_value                        matlab.ui.control.Label
        B_value                        matlab.ui.control.Label
        alpha_value                    matlab.ui.control.Label
        D_Label                        matlab.ui.control.Label
        B_Label                        matlab.ui.control.Label
        C_Label                        matlab.ui.control.Label
        alpha_Label                    matlab.ui.control.Label
        gamma_Slider                   matlab.ui.control.Slider
        gammaLabel                     matlab.ui.control.Label
        r_0_Slider                     matlab.ui.control.Slider
        r_0SliderLabel                 matlab.ui.control.Label
        epsilon_Slider                 matlab.ui.control.Slider
        epsilonLabel                   matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Interaction_Plot               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Qmin = 38.8722633361816; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeData(app)
            UpdateAppData(app, [])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TFPotentialCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {470, 470};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: epsilon_Slider, gamma_Slider, 
        % r_0_Slider
        function UpdateAppData(app, event)
            epsilon = app.epsilon_Slider.Value;
            r0 = app.r_0_Slider.Value;
            gamma = app.gamma_Slider.Value;
            
            k = 1/(2 - (gamma/6) - (gamma/8));
            
            % Calculate Tight form Parameters
            alpha = gamma/r0;                                      % Exp Steepness parameter
            B = 48*epsilon*exp(gamma)/(48 - 4*gamma - 3*gamma);    % Repulsive prefactor
            C = 4*gamma*(r0^6)*epsilon/(48 - 4*gamma - 3*gamma);   % 1/r6 dispersion
            D = (3/4)*(r0^2)*C;                                    % 1/r8 dispersion
            
            % Update text values
            app.alpha_value.Text = num2str(alpha,'%15.9f');
            app.B_value.Text = num2str(B,'%15.9f');
            app.C_value.Text = num2str(C,'%15.9f');
            app.D_value.Text = num2str(D,'%15.9f');
            app.D_over_C_value.Text = num2str(D/C,'%15.9f');
            app.Dalpha2_over_C_value.Text = num2str(D*(alpha^2)/C,'%15.9f');
            
            ffun = @(r)2*epsilon.*k.*exp(gamma.*(1 - (r./r0))) - (epsilon*gamma*k/6).*((r0./r).^6) - (epsilon*gamma*k/8).*((r0./r).^8);
            fpfun = @(r)-2*epsilon*gamma*k.*exp(gamma.*(1 - (r./r0)))./r0 + epsilon*gamma*k*((r0.^6)./(r.^7)) + epsilon*gamma*k*((r0.^8)./(r.^9));
            rt = fzero(fpfun,app.Qmin*r0/gamma);
            
            fmin = ffun(rt);
            fpmin = fpfun(rt);
            
            % Check if rt is larger than the x limits
            if rt > 19
                r = 0:0.001:rt+1;
                xmax = rt+1;
            else
                r = 0:0.001:20;
                xmax = 20;
            end
            
            % Calculate potential
            f = 2*epsilon.*k.*exp(gamma.*(1 - (r./r0))) - (epsilon*gamma*k/6).*((r0./r).^6) - (epsilon*gamma*k/8).*((r0./r).^8);
            
            % Derivative of potential
            fprime = -2*epsilon*gamma*k.*exp(gamma.*(1 - (r./r0)))./r0 + epsilon*gamma*k*((r0.^6)./(r.^7)) + epsilon*gamma*k*((r0.^8)./(r.^9));
            
            % Update plot
            ax = app.Interaction_Plot;
            cla(ax)
            hold(ax,'on')
            yline(ax,0,':k',"LineWidth",1)
            plot(ax,r,f,'-b',r,fprime,'.-r',"LineWidth",3)
            scatter(ax,rt,fpmin,50,'red','filled','o')
            scatter(ax,r0,fpfun(r0),50,'red','filled','o')
            
            scatter(ax,rt,fmin,50,'blue','filled','o')
            scatter(ax,r0,ffun(r0),50,'blue','filled','o')
            ylim(ax,[-10 10])
            xlim(ax,[0,xmax])
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFPotentialCalculatorUIFigure = uifigure('Visible', 'off');
            app.TFPotentialCalculatorUIFigure.Name = 'Exp C6 C8 Loose Form';
            app.TFPotentialCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TFPotentialCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.epsilonLabel = uilabel(app.LeftPanel);
            app.epsilonLabel.Interpreter = 'latex';
            app.epsilonLabel.Text = '$\epsilon$';
            app.epsilon_Slider = uislider(app.LeftPanel);
            app.epsilon_Slider.Limits = [0 20];
            app.epsilon_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.epsilon_Slider.Value = 10;
            app.r_0SliderLabel = uilabel(app.LeftPanel);
            app.r_0SliderLabel.Interpreter = 'latex';
            app.r_0SliderLabel.Text = '$r_{0}$';
            app.r_0_Slider = uislider(app.LeftPanel);
            app.r_0_Slider.Limits = [0 20];
            app.r_0_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.r_0_Slider.Value = 1;
            app.gammaLabel = uilabel(app.LeftPanel);
            app.gammaLabel.Interpreter = 'latex';
            app.gammaLabel.Text = '$\gamma$';
            app.gamma_Slider = uislider(app.LeftPanel);
            app.gamma_Slider.Limits = [0 7];
            app.gamma_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.gamma_Slider.Value = 1;
            app.alpha_Label = uilabel(app.LeftPanel);
            app.alpha_Label.Interpreter = 'latex';
            app.alpha_Label.Text = '$\alpha$ =';
            app.C_Label = uilabel(app.LeftPanel);
            app.C_Label.Interpreter = 'latex';
            app.C_Label.Text = '$C$ =';
            app.B_Label = uilabel(app.LeftPanel);
            app.B_Label.Interpreter = 'latex';
            app.B_Label.Text = '$B$ =';
            app.D_Label = uilabel(app.LeftPanel);
            app.D_Label.Interpreter = 'latex';
            app.D_Label.Text = '$D$ =';
            app.alpha_value = uilabel(app.LeftPanel);
            app.alpha_value.Interpreter = 'latex';
            app.alpha_value.Text = '0';
            app.B_value = uilabel(app.LeftPanel);
            app.B_value.Interpreter = 'latex';
            app.B_value.Text = '0';
            app.C_value = uilabel(app.LeftPanel);
            app.C_value.Interpreter = 'latex';
            app.C_value.Text = '0';
            app.D_value = uilabel(app.LeftPanel);
            app.D_value.Interpreter = 'latex';
            app.D_value.Text = '0';
            app.D_over_C_Label = uilabel(app.LeftPanel);
            app.D_over_C_Label.Interpreter = 'latex';
            app.D_over_C_Label.Text = '$\frac{D}{C}$ =';
            app.D_over_C_value = uilabel(app.LeftPanel);
            app.D_over_C_value.Interpreter = 'latex';
            app.D_over_C_value.Text = '0';
            app.Dalpha2_over_C_Label = uilabel(app.LeftPanel);
            app.Dalpha2_over_C_Label.Interpreter = 'latex';
            app.Dalpha2_over_C_Label.Text = '$\frac{D}{C}\alpha^2$ =';
            app.Dalpha2_over_C_value = uilabel(app.LeftPanel);
            app.Dalpha2_over_C_value.Interpreter = 'latex';
            app.Dalpha2_over_C_value.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.Interaction_Plot = uiaxes(app.RightPanel);
            title(app.Interaction_Plot, 'Model Plot')
            xlabel(app.Interaction_Plot, 'r')
            ylabel(app.Interaction_Plot, 'U(r)')
            % Show the figure after all components are created
            app.TFPotentialCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exp_C6_C8_Examiner_ExtraDim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TFPotentialCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitializeData)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFPotentialCalculatorUIFigure)
        end
    end
end