classdef Exp_C6_C8_Examiner_TightForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFPotentialCalculatorUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        xLabel_3                       matlab.ui.control.Label
        xLabel_2                       matlab.ui.control.Label
        xLabel                         matlab.ui.control.Label
        Info_Label                     matlab.ui.control.Label
        DLabel                         matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        D_Slider                       matlab.ui.control.Slider
        BLabel                         matlab.ui.control.Label
        alphaLabel                     matlab.ui.control.Label
        alpha_Slider                   matlab.ui.control.Slider
        B_Slider                       matlab.ui.control.Slider
        C_Slider                       matlab.ui.control.Slider
        Dalpha2_over_C_value           matlab.ui.control.Label
        gamma_Label                    matlab.ui.control.Label
        epsilon_Label                  matlab.ui.control.Label
        r_0_Label                      matlab.ui.control.Label
        Dalpha2_over_C_Label           matlab.ui.control.Label
        gamma_value                    matlab.ui.control.Label
        epsilon_value                  matlab.ui.control.Label
        r_0_value                      matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Interaction_Plot               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Qmin = 38.8722633361816; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeData(app)
            UpdateAppData(app, [])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TFPotentialCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {470, 470};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: B_Slider, C_Slider, D_Slider, 
        % alpha_Slider
        function UpdateAppData(app, event)
            alpha = app.alpha_Slider.Value; % Exp Steepness parameter
            B = 10^(app.B_Slider.Value);         % Repulsive prefactor
            C = 10^(app.C_Slider.Value);         % 1/r6 dispersion
            D = 10^(app.D_Slider.Value);         % 1/r8 dispersion
            
            gamma = (2/3)*sqrt(3*D/C)*alpha; % Steepness parameter
            r0 = (2/3)*sqrt(3*D/C); % Well minima location
            epsilon = B*exp(-gamma)*(1 - (7/48)*gamma); % Well depth
            %epsilon = B*exp(-sqrt( (4/3)*(alpha^2)*(D/C) ))*( 1 - (7/72)*alpha*sqrt(3*D/C) );
            %epsilon = 12*C/((128/2187)*alpha*((3*D/C)^(7/2))) - 7*C/((256/729)*((3*D/C)^3));
            
            k = 1/(2 - (gamma/6) - (gamma/8));
            
            % Update text values
            app.gamma_value.Text = num2str(gamma,'%15.9f');
            app.r_0_value.Text = num2str(r0,'%15.9f');
            app.epsilon_value.Text = num2str(epsilon,'%15.9f');
            app.Dalpha2_over_C_value.Text = num2str(D*(alpha^2)/C,'%15.9f');
            
            ffun = @(r)B*exp(-alpha.*r) - C./(r.^6) - D./(r.^8);
            fpfun = @(r)-alpha*B*exp(-alpha.*r) + 6*C./(r.^7) + 8*D./(r.^9);
            rt = fzero(fpfun,app.Qmin*r0/gamma);
            
            fmin = ffun(rt);
            fpmin = fpfun(rt);
            
            % Check if rt is larger than the x limits
            if rt > 19
                r = 0:0.001:rt+1;
                xmax = rt+1;
            else
                r = 0:0.001:20;
                xmax = 20;
            end
            
            % Calculate potential
            %f = 2*epsilon.*k.*exp(gamma.*(1 - (r./r0))) - (epsilon*gamma*k/6).*((r0./r).^6) - (epsilon*gamma*k/8).*((r0./r).^8);
            f = B*exp(-alpha.*r) - C./(r.^6) - D./(r.^8);
            
            % Derivative of potential
            %fprime = -2*epsilon*gamma*k.*exp(gamma.*(1 - (r./r0)))./r0 + epsilon*gamma*k*((r0.^6)./(r.^7)) + epsilon*gamma*k*((r0.^8)./(r.^9));
            fprime = -alpha*B*exp(-alpha.*r) + 6*C./(r.^7) + 8*D./(r.^9);
            
            % Update plot
            ax = app.Interaction_Plot;
            cla(ax)
            hold(ax,'on')
            yline(ax,0,':k',"LineWidth",1)
            plot(ax,r,f,'-b',r,fprime,'.-r',"LineWidth",3)
            scatter(ax,rt,fpmin,50,'red','filled','o')
            scatter(ax,r0,fpfun(r0),50,'red','filled','o')
            
            scatter(ax,rt,fmin,50,'blue','filled','o')
            scatter(ax,r0,ffun(r0),50,'blue','filled','o')
            ylim(ax,[-10 10])
            xlim(ax,[0,xmax])
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFPotentialCalculatorUIFigure = uifigure('Visible', 'off');
            app.TFPotentialCalculatorUIFigure.Name = 'Exp C6 C8 Tight Form';
            app.TFPotentialCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TFPotentialCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.r_0_value = uilabel(app.LeftPanel);
            app.r_0_value.Interpreter = 'latex';
            app.r_0_value.Text = '0';
            app.epsilon_value = uilabel(app.LeftPanel);
            app.epsilon_value.Interpreter = 'latex';
            app.epsilon_value.Text = '0';
            app.gamma_value = uilabel(app.LeftPanel);
            app.gamma_value.Interpreter = 'latex';
            app.gamma_value.Text = '0';
            app.Dalpha2_over_C_Label = uilabel(app.LeftPanel);
            app.Dalpha2_over_C_Label.Interpreter = 'latex';
            app.Dalpha2_over_C_Label.Text = '$\frac{D}{C}\alpha^{2}$ =';
            app.r_0_Label = uilabel(app.LeftPanel);
            app.r_0_Label.Interpreter = 'latex';
            app.r_0_Label.Text = '$r_{0}$ =';
            app.epsilon_Label = uilabel(app.LeftPanel);
            app.epsilon_Label.Interpreter = 'latex';
            app.epsilon_Label.Text = '$\epsilon$ =';
            app.gamma_Label = uilabel(app.LeftPanel);
            app.gamma_Label.Interpreter = 'latex';
            app.gamma_Label.Text = '$\gamma$ =';
            app.Dalpha2_over_C_value = uilabel(app.LeftPanel);
            app.Dalpha2_over_C_value.Interpreter = 'latex';
            app.Dalpha2_over_C_value.Text = '0';
            app.C_Slider = uislider(app.LeftPanel);
            app.C_Slider.Limits = [-8 8];
            app.C_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.B_Slider = uislider(app.LeftPanel);
            app.B_Slider.Limits = [-8 8];
            app.B_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.alpha_Slider = uislider(app.LeftPanel);
            app.alpha_Slider.Limits = [0 20];
            app.alpha_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.alpha_Slider.Value = 10;
            app.alphaLabel = uilabel(app.LeftPanel);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '$\alpha$';
            app.BLabel = uilabel(app.LeftPanel);
            app.BLabel.Interpreter = 'latex';
            app.BLabel.Text = '$B$';
            app.D_Slider = uislider(app.LeftPanel);
            app.D_Slider.Limits = [-8 8];
            app.D_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.CLabel = uilabel(app.LeftPanel);
            app.CLabel.Interpreter = 'latex';
            app.CLabel.Text = '$C$';
            app.DLabel = uilabel(app.LeftPanel);
            app.DLabel.Interpreter = 'latex';
            app.DLabel.Text = '$D$';
            app.Info_Label = uilabel(app.LeftPanel);
            app.Info_Label.Interpreter = 'latex';
            app.Info_Label.Text = {'$\frac{D}{C}\alpha^{2}$ must remain less than'; '$\frac{1728}{49} \approx 35.265$'};
            app.xLabel = uilabel(app.LeftPanel);
            app.xLabel.Interpreter = 'latex';
            app.xLabel.Text = '$10^x$';
            app.xLabel_2 = uilabel(app.LeftPanel);
            app.xLabel_2.Interpreter = 'latex';
            app.xLabel_2.Text = '$10^x$';
            app.xLabel_3 = uilabel(app.LeftPanel);
            app.xLabel_3.Interpreter = 'latex';
            app.xLabel_3.Text = '$10^x$';
            app.RightPanel = uipanel(app.GridLayout);
            app.Interaction_Plot = uiaxes(app.RightPanel);
            title(app.Interaction_Plot, 'Model Plot')
            xlabel(app.Interaction_Plot, 'r')
            ylabel(app.Interaction_Plot, 'U(r)')
            % Show the figure after all components are created
            app.TFPotentialCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exp_C6_C8_Examiner_TightForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TFPotentialCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitializeData)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFPotentialCalculatorUIFigure)
        end
    end
end