classdef Lennard_Jones_Examiner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TFPotentialCalculatorUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        n_Slider                       matlab.ui.control.Slider
        nLabel                         matlab.ui.control.Label
        PlotDropDown                   matlab.ui.control.DropDown
        PlotDropDownLabel              matlab.ui.control.Label
        IncludeDerivativeCheckBox      matlab.ui.control.CheckBox
        C_value                        matlab.ui.control.Label
        B_value                        matlab.ui.control.Label
        B_Label                        matlab.ui.control.Label
        C_Label                        matlab.ui.control.Label
        r_0_Slider                     matlab.ui.control.Slider
        sigmaLabel                     matlab.ui.control.Label
        epsilon_Slider                 matlab.ui.control.Slider
        epsilonLabel                   matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Interaction_Plot               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Qmin = 38.8722633361816; % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function InitializeData(app)
            UpdateAppData(app, [])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.TFPotentialCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {470, 470};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: IncludeDerivativeCheckBox, 
        % PlotDropDown, epsilon_Slider, n_Slider, r_0_Slider
        function UpdateAppData(app, event)
            
            PlotType = app.PlotDropDown.Value;
            epsilon = app.epsilon_Slider.Value;
            sigma = app.r_0_Slider.Value;
            n = app.n_Slider.Value;
            
            prefactor = (n/(n-6))*((n/6)^(6/(n-6)));
            
            % Calculate Tight form Parameters
            B = prefactor*epsilon*(sigma^n); % Repulsive prefactor
            C = prefactor*epsilon*(sigma^6); % 1/r6 dispersion
            
            % Update text values
            app.B_value.Text = num2str(B,'%15.9f');
            app.C_value.Text = num2str(C,'%15.9f');     
            
            switch PlotType
            case 'LJ'
                ffun = @(r) prefactor*epsilon.*( ((sigma./r).^n) - ((sigma./r).^6) );
                fpfun = @(r) -prefactor*epsilon.*( n.*((sigma./r).^(n+1)) - 6.*((sigma./r).^7) );
                rt = fzero(fpfun,sigma);
                
                fmin = ffun(rt);
                fpmin = fpfun(rt);
                
                % Check if rt is larger than the x limits
                if rt > 18.19
                    r = 0:0.001:rt+rt*1.1;
                    xmax = rt+rt*1.1;
                else
                    r = 0:0.001:20;
                    xmax = 20;
                end
                
                % Calculate potential
                f = ffun(r);
                
                % Derivative of potential
                fprime = fpfun(r);
                ylims = [-10 10];
            case {'Full Attractive' 'Full Repulsive'}
                
                if strcmp(PlotType,'Full Attractive')
                    q1 = 1;
                    q2 = -1;
                else
                    q1 = 1;
                    q2 = 1;
                end
                Ang_per_m = 1e+10; % Ang per m
                NA = 6.0221409e23; % Molecules per mole
                e_c = 1.60217662e-19; % Elementary charge in Coulombs
                epsilon_0 = (8.854187817620e-12)*1000/(Ang_per_m*NA); % Vacuum Permittivity C^2 mol kJ^-1 Ang^-1
                k_0 = 1/(4*pi*epsilon_0); % Coulomb constant in kJ Ang C^-2 mol^-1
                
                ffun = @(r) prefactor*epsilon.*( ((sigma./r).^n) - ((sigma./r).^6) ) + ...
                    k_0*(e_c^2)*q1*q2./(r);
                fpfun = @(r) -(prefactor*n)*epsilon.*( ((sigma./r).^(n+1)) - (1/2).*((sigma./r).^7) ) - ...
                    k_0*(e_c^2)*q1*q2./(r.^2);
                
                rt = fzero(fpfun,sigma);
                
                fmin = ffun(rt);
                fpmin = fpfun(rt);
                
                % Check if rt is larger than the x limits
                if rt > 18.19
                    r = 0:0.001:rt+rt*1.1;
                    xmax = rt+rt*1.1;
                else
                    r = 0:0.001:20;
                    xmax = 20;
                end
                
                % Calculate potential
                f = ffun(r);
                
                % Derivative of potential
                fprime = fpfun(r);
                ylims = [-1000 1000];
            end


            % Update plot
            ax = app.Interaction_Plot;
            cla(ax)
            hold(ax,'on')
            yline(ax,0,':k',"LineWidth",1)
            
            
            if app.IncludeDerivativeCheckBox.Value
                plot(ax,r,f,'-b',r,fprime,'.-r',"LineWidth",3)
                scatter(ax,rt,fpmin,50,'red','filled','o')
                scatter(ax,sigma,fpfun(sigma),50,'red','filled','o')
                
                scatter(ax,rt,fmin,50,'blue','filled','o')
                scatter(ax,sigma,ffun(sigma),50,'blue','filled','o')
            else
                plot(ax,r,f,'-b',"LineWidth",3)
                
                scatter(ax,rt,fmin,50,'blue','filled','o')
                scatter(ax,sigma,ffun(sigma),50,'blue','filled','o')
            end
            
            ylim(ax,ylims)
            xlim(ax,[0,xmax])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TFPotentialCalculatorUIFigure = uifigure('Visible', 'off');
            app.TFPotentialCalculatorUIFigure.Name = 'Exp C6 C8 Loose Form';
            app.TFPotentialCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.TFPotentialCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.epsilonLabel = uilabel(app.LeftPanel);
            app.epsilonLabel.Interpreter = 'latex';
            app.epsilonLabel.Text = '$\epsilon$';
            app.epsilon_Slider = uislider(app.LeftPanel);
            app.epsilon_Slider.Limits = [0 400];
            app.epsilon_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.epsilon_Slider.Value = 5;
            app.sigmaLabel = uilabel(app.LeftPanel);
            app.sigmaLabel.Interpreter = 'latex';
            app.sigmaLabel.Text = {'$\sigma$'; ''};
            app.r_0_Slider = uislider(app.LeftPanel);
            app.r_0_Slider.Limits = [0 10];
            app.r_0_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.r_0_Slider.Value = 5;
            app.C_Label = uilabel(app.LeftPanel);
            app.C_Label.Interpreter = 'latex';
            app.C_Label.Text = '$C$ =';
            app.B_Label = uilabel(app.LeftPanel);
            app.B_Label.Interpreter = 'latex';
            app.B_Label.Text = '$B$ =';
            app.B_value = uilabel(app.LeftPanel);
            app.B_value.Interpreter = 'latex';
            app.B_value.Text = '0';
            app.C_value = uilabel(app.LeftPanel);
            app.C_value.Interpreter = 'latex';
            app.C_value.Text = '0';
            app.IncludeDerivativeCheckBox = uicheckbox(app.LeftPanel);
            app.IncludeDerivativeCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.IncludeDerivativeCheckBox.Text = 'Include Derivative';
            app.IncludeDerivativeCheckBox.Value = true;
            app.PlotDropDownLabel = uilabel(app.LeftPanel);
            app.PlotDropDownLabel.Text = 'Plot:';
            app.PlotDropDown = uidropdown(app.LeftPanel);
            app.PlotDropDown.Items = {'LJ', 'Full Attractive', 'Full Repulsive'};
            app.PlotDropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.PlotDropDown.Value = 'LJ';
            app.nLabel = uilabel(app.LeftPanel);
            app.nLabel.Interpreter = 'latex';
            app.nLabel.Text = {'n'; ''};
            app.n_Slider = uislider(app.LeftPanel);
            app.n_Slider.Limits = [4 20];
            app.n_Slider.ValueChangedFcn = createCallbackFcn(app, @UpdateAppData, true);
            app.n_Slider.Value = 12;
            app.RightPanel = uipanel(app.GridLayout);
            app.Interaction_Plot = uiaxes(app.RightPanel);
            title(app.Interaction_Plot, 'Model Plot')
            xlabel(app.Interaction_Plot, 'r')
            ylabel(app.Interaction_Plot, 'U(r)')
            % Show the figure after all components are created
            app.TFPotentialCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lennard_Jones_Examiner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TFPotentialCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @InitializeData)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TFPotentialCalculatorUIFigure)
        end
    end
end