classdef compare_energy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotOptionsMenuUIFigure      matlab.ui.Figure
        FontSizeEditFieldLabel       matlab.ui.control.Label
        FontSizeEditField            matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel      matlab.ui.control.Label
        LineWidthEditField           matlab.ui.control.NumericEditField
        XAxisUnitsButtonGroup        matlab.ui.container.ButtonGroup
        aButton                      matlab.ui.control.ToggleButton
        BLButton                     matlab.ui.control.ToggleButton
        VButton                      matlab.ui.control.ToggleButton
        PButton                      matlab.ui.control.ToggleButton
        DensityButton                matlab.ui.control.ToggleButton
        XAxisExtentEditFieldLabel    matlab.ui.control.Label
        XAxisExtentEditField         matlab.ui.control.NumericEditField
        SaltsPairsListBoxLabel       matlab.ui.control.Label
        SaltsPairsListBox            matlab.ui.control.ListBox
        StructuresListBoxLabel       matlab.ui.control.Label
        StructuresListBox            matlab.ui.control.ListBox
        SecondaryAxisButtonGroup     matlab.ui.container.ButtonGroup
        NoPlotButton                 matlab.ui.control.ToggleButton
        CPUTimePlotButton            matlab.ui.control.ToggleButton
        BSSEPlotButton               matlab.ui.control.ToggleButton
        XAxisAlignmentButtonGroup    matlab.ui.container.ButtonGroup
        XButton                      matlab.ui.control.ToggleButton
        XXminButton                  matlab.ui.control.ToggleButton
        YAxisAlignmentButtonGroup    matlab.ui.container.ButtonGroup
        YButton                      matlab.ui.control.ToggleButton
        YYminButton                  matlab.ui.control.ToggleButton
        InterpolationButtonGroup     matlab.ui.container.ButtonGroup
        Switch                       matlab.ui.control.Switch
        DropDown                     matlab.ui.control.DropDown
        Button_2                     matlab.ui.control.Button
        StepSizegcmEditFieldLabel    matlab.ui.control.Label
        StepSizegcmEditField         matlab.ui.control.NumericEditField
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        PrimaryAxisButtonGroup       matlab.ui.container.ButtonGroup
        LinePlotButton               matlab.ui.control.ToggleButton
        ScatterPlotButton            matlab.ui.control.ToggleButton
        BarPlotButton                matlab.ui.control.ToggleButton
        PlotButton                   matlab.ui.control.Button
        YAxisUnitsButtonGroup_2      matlab.ui.container.ButtonGroup
        LatticeEnergyButton          matlab.ui.control.ToggleButton
        CohesiveEnergyButton         matlab.ui.control.ToggleButton
        PressureButton_2             matlab.ui.control.ToggleButton
        ColourSchemeDropDownLabel    matlab.ui.control.Label
        ColourSchemeDropDown         matlab.ui.control.DropDown
        TheoriesListBoxLabel         matlab.ui.control.Label
        TheoriesListBox              matlab.ui.control.ListBox
        FileNameEditFieldLabel       matlab.ui.control.Label
        FileNameEditField            matlab.ui.control.EditField
        DataTypesListBoxLabel        matlab.ui.control.Label
        DataTypesListBox             matlab.ui.control.ListBox
        AutoPlotButtonGroup          matlab.ui.container.ButtonGroup
        EnableButton                 matlab.ui.control.ToggleButton
        DisableButton                matlab.ui.control.ToggleButton
        Button                       matlab.ui.control.Button
        PreviewDataButton            matlab.ui.control.Button
        ExperimentalDataButtonGroup  matlab.ui.container.ButtonGroup
        NotincludedButton_2          matlab.ui.control.ToggleButton
        IncludedButton_2             matlab.ui.control.ToggleButton
        BSSECorrectionButtonGroup    matlab.ui.container.ButtonGroup
        NoCorrectionButton           matlab.ui.control.ToggleButton
        CorrectionButton             matlab.ui.control.ToggleButton
        BasisSetListBoxLabel         matlab.ui.control.Label
        BasisSetListBox              matlab.ui.control.ListBox
        QMOnlyLabel                  matlab.ui.control.Label
        FormattedTablesButton        matlab.ui.control.Button
        ScaleEmpiricalListBoxLabel   matlab.ui.control.Label
        ScaleEmpiricalListBox        matlab.ui.control.ListBox
        ScaleEditField               matlab.ui.control.NumericEditField
        CurrentScalesPanel           matlab.ui.container.Panel
        ScaleListBox                 matlab.ui.control.ListBox
        ButtonRemoveScale            matlab.ui.control.Button
        ButtonAddScale               matlab.ui.control.Button
        EmpDampingListBoxLabel       matlab.ui.control.Label
        EmpDampingListBox            matlab.ui.control.ListBox
    end

    
    properties (Access = public)
        settings % Settings for the app stored here, passed to other functions
        figureh % Figure Handle
        CrosshairButton % Toolbar crosshair button
        MenuButton % Toolbar plot Menu button
    end

    methods (Access = private)
    
        function app = updateAppSettings(app)
            % By default, print table is turned off
            app.settings.PrintTable = false;
            
            % Get Font Size
            app.settings.fs = app.FontSizeEditField.Value;
            
            % Get Line Width
            app.settings.lw = app.LineWidthEditField.Value;
            
            % Get Basis Set(s)
            app.settings.Basis_set = app.BasisSetListBox.Value;
            
            % Get Colour Scheme
            app.settings.Color_Scheme = app.ColourSchemeDropDown.Value;
            
            % Get X-Axis extent
            app.settings.ep = app.XAxisExtentEditField.Value;
            
            % Get Salt Types
            app.settings.Salts = app.SaltsPairsListBox.Value;
            
            % Get Structures
            app.settings.Structures = app.StructuresListBox.Value;
            
            % Get Primary Axis Type
            if app.LinePlotButton.Value
                app.settings.plot_style = 'Line';
            elseif app.ScatterPlotButton.Value
                app.settings.plot_style = 'Scatter';
            elseif app.BarPlotButton.Value
                app.settings.plot_style = 'Bar';
            end
            
            % Get Secondary Axis Option
            if app.NoPlotButton.Value
                app.settings.plot_Time = false;
                app.settings.plot_BSSE = false;
            elseif app.CPUTimePlotButton.Value
                app.settings.plot_Time = true;
                app.settings.plot_BSSE = false;
            elseif app.BSSEPlotButton.Value
                app.settings.plot_Time = false;
                app.settings.plot_BSSE = true;
            end
            
            % Get X-Axis Units Option
            if app.aButton.Value
                app.settings.x_axis = "a";
            elseif app.BLButton.Value
                app.settings.x_axis = "bl";
            elseif app.VButton.Value
                app.settings.x_axis = "V";
            elseif app.PButton.Value
                app.settings.x_axis = "P";
            elseif app.DensityButton.Value
                app.settings.x_axis = "d";
            end
            
            % Get X-Axis Alignment Option
            if app.XButton.Value
                app.settings.x_align = false;
            elseif app.XXminButton.Value
                app.settings.x_align = true;
            end
            
            % Get Y-Axis Alignment Option
            if app.YButton.Value
                app.settings.E_align = false;
            elseif app.YYminButton.Value
                app.settings.E_align = true;
            end
            
            % Get BSSE Correction
            if app.NoCorrectionButton.Value
                app.settings.BSSE_Correct = false;
            elseif app.CorrectionButton.Value
                app.settings.BSSE_Correct = true;
            end
            
            %  Get Interpolation Option
            app.settings.InterpolationMethod = app.DropDown.Value;
            app.settings.InterpolationSize = app.StepSizegcmEditField.Value;
            if strcmp(app.Switch.Value,'On')
                app.settings.InterpolationSwitch = true;
            elseif strcmp(app.Switch.Value,'Off')
                app.settings.InterpolationSwitch = false;
            end
            
            % Get Y-Axis Units Option
            if app.LatticeEnergyButton.Value
                app.settings.Y_Axis_Units = 'L';
            elseif app.CohesiveEnergyButton.Value
                app.settings.Y_Axis_Units = 'C';
            elseif app.PressureButton_2.Value
                app.settings.Y_Axis_Units = 'P';
            end
            
            % Get Experimental Data Option
            if app.NotincludedButton_2.Value
                app.settings.plot_Experimental = false;
            elseif app.IncludedButton_2.Value
                app.settings.plot_Experimental = true;
            end
            
            % Get theories
            app.settings.plotDFT = strrep(app.TheoriesListBox.Value,'-','_');
            
            % Get file name
            app.settings.Table_Filename = app.FileNameEditField.Value;
            
            % Get data types list
            app.settings.Data_Types = app.DataTypesListBox.Value;
            
            % Get Empirical Damping list
            app.settings.Emp_Damp = app.EmpDampingListBox.Value;
            
            % Get auto plot state
            app.settings.Auto_Plot = app.EnableButton.Value;
            
            % Get type of empirical scaling selected
            app.settings.ScalingType = app.ScaleEmpiricalListBox.Value;
            
            % Get scaling values
            app.settings.Scaling = app.ScaleListBox.Items;
            
        end
    end

    methods (Access = public)        
        function OptionsMenufcn(app) % Switch to turn visibility of menu off and on
            if strcmpi(app.PlotOptionsMenuUIFigure.Visible,'off')
                app.PlotOptionsMenuUIFigure.Visible = 'on';
            else
                app.PlotOptionsMenuUIFigure.Visible = 'off';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load app settings
            home = fileparts(mfilename('fullpath'));
            
            load(fullfile(home,'AppData','Compare_PES_App_Settings.mat'),'-mat','app_settings')
            app.settings = app_settings;
            
            % Home directory
            app.settings.home = home;
            
            % Update Font Size
            app.FontSizeEditField.Value = app.settings.fs;
            
            % Update Line Width
            app.LineWidthEditField.Value = app.settings.lw;
            
            % Update Basis Set(s)
            app.BasisSetListBox.Value = app.settings.Basis_set;
            
            % Update X-axis type settings
            if app.settings.x_axis == "a"
                app.aButton.Value = true;
                app.BLButton.Value = false;
                app.VButton.Value = false;
                app.PButton.Value = false;
                app.DensityButton.Value = false;
                app.Label.Visible = 'Off';
                app.Label_2.Visible = 'Off';
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (Å)';
            elseif app.settings.x_axis == "bl"
                app.aButton.Value = false;
                app.BLButton.Value = true;
                app.VButton.Value = false;
                app.PButton.Value = false;
                app.DensityButton.Value = false;
                app.Label.Visible = 'Off';
                app.Label_2.Visible = 'Off';
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (Å)';
            elseif app.settings.x_axis == "V"
                app.aButton.Value = false;
                app.BLButton.Value = false;
                app.VButton.Value = true;
                app.PButton.Value = false;
                app.DensityButton.Value = false;
                app.Label.Visible = 'On';
                app.Label_2.Visible = 'Off';
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (cm /mol)';
            elseif app.settings.x_axis == "P"
                app.aButton.Value = false;
                app.BLButton.Value = false;
                app.VButton.Value = false;
                app.PButton.Value = true;
                app.DensityButton.Value = false;
                app.Label.Visible = 'Off';
                app.Label_2.Visible = 'Off';
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (GPa)';
            elseif app.settings.x_axis == "d"
                app.aButton.Value = false;
                app.BLButton.Value = false;
                app.VButton.Value = false;
                app.PButton.Value = false;
                app.DensityButton.Value = true;
                app.Label.Visible = 'Off';
                app.Label_2.Visible = 'On';
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (g/cm )';
            end
            
            % Update X-Axis Extent
            app.XAxisExtentEditField.Value = app.settings.ep;
            
            % Update Salt Types
            app.SaltsPairsListBox.Value = app.settings.Salts;
            
            % Update Structures List
            app.StructuresListBox.Value = app.settings.Structures;
            
            % Update Secondary plot setting
            if ~app.settings.plot_Time && ~app.settings.plot_BSSE
                app.NoPlotButton.Value = true;
                app.CPUTimePlotButton.Value = false;
                app.BSSEPlotButton.Value = false;
            elseif app.settings.plot_Time && ~app.settings.plot_BSSE
                app.NoPlotButton.Value = false;
                app.CPUTimePlotButton.Value = true;
                app.BSSEPlotButton.Value = false;
            elseif ~app.settings.plot_Time && app.settings.plot_BSSE
                app.NoPlotButton.Value = false;
                app.CPUTimePlotButton.Value = false;
                app.BSSEPlotButton.Value = true;
            end
            
            % Update X axis alignment settings
            app.XButton.Value = ~app.settings.x_align;
            app.XXminButton.Value = app.settings.x_align;
            
            % Update Energy alignment settings
            app.YButton.Value = ~app.settings.E_align;
            app.YYminButton.Value = app.settings.E_align;
            
            % Update Interpolation Settings
            if app.settings.InterpolationSwitch
                app.Switch.Value = 'On';
                app.DropDown.Value = app.settings.InterpolationMethod;
                app.StepSizegcmEditField.Value = app.settings.InterpolationSize;
            else
                app.Switch.Value = 'Off';
                app.DropDown.Value = app.settings.InterpolationMethod;
                app.StepSizegcmEditField.Value = app.settings.InterpolationSize;
            end           
            
            % Update BSSE Correction settings
            app.NoCorrectionButton.Value = ~app.settings.BSSE_Correct;
            app.CorrectionButton.Value = app.settings.BSSE_Correct;
            
            % Update primary plot type setting
            if strcmp(app.settings.plot_style,'Line')
                app.LinePlotButton.Value = true;
                app.ScatterPlotButton.Value = false;
                app.BarPlotButton.Value = false;
            elseif strcmp(app.settings.plot_style,'Scatter')
                app.LinePlotButton.Value = false;
                app.ScatterPlotButton.Value = true;
                app.BarPlotButton.Value = false;
            elseif strcmp(app.settings.plot_style,'Bar')
                app.LinePlotButton.Value = false;
                app.ScatterPlotButton.Value = false;
                app.BarPlotButton.Value = true;
            end
            
            % Update include expiermental settings
            app.NotincludedButton_2.Value = ~app.settings.plot_Experimental;
            app.IncludedButton_2.Value = app.settings.plot_Experimental;
            
            % Update Y-Axis units
            if strcmp(app.settings.Y_Axis_Units,'L')
                app.LatticeEnergyButton.Value = true;
                app.CohesiveEnergyButton.Value = false;
                app.PressureButton_2.Value = false;
            elseif strcmp(app.settings.Y_Axis_Units,'C')
                app.LatticeEnergyButton.Value = false;
                app.CohesiveEnergyButton.Value = true;
                app.PressureButton_2.Value = false;
            elseif strcmp(app.settings.Y_Axis_Units,'P')
                app.LatticeEnergyButton.Value = false;
                app.CohesiveEnergyButton.Value = false;
                app.PressureButton_2.Value = true;
            end
            
            % Update Colour Scheme
            app.ColourSchemeDropDown.Value = app.settings.Color_Scheme;
            
            % Update Theories List
            app.TheoriesListBox.Value = strrep(app.settings.plotDFT,'_','-');
            
            % Update table file name
            app.FileNameEditField.Value = app.settings.Table_Filename;
            
            % Update data types list
            app.DataTypesListBox.Value = app.settings.Data_Types;
            
            % Update Empirical Damping list
            app.EmpDampingListBox.Value = app.settings.Emp_Damp;
            
            % Auto plot button
            app.EnableButton.Value = app.settings.Auto_Plot;
            app.DisableButton.Value = ~app.settings.Auto_Plot;
            
            % Table settings
            app.settings.PreviewTable = false;
            app.settings.FormattedTables = false;
            
            % Type of empirical scaling settings
            app.ScaleEmpiricalListBox.Value = app.settings.ScalingType;
            
            % Scaling values Settings
            app.ScaleListBox.Items = app.settings.Scaling;
            
            % Generate plotting figure
            figtitle = 'Data Derived From CRYSTAL17 and/or GROMACS 5.1.4';
            app.figureh = figure('WindowState','maximized','NumberTitle','off',...
                'Name',figtitle,'Visible','on');
            app.figureh.CloseRequestFcn = {@CloseAppfcn,app};
            
            set(0,'Showhidden','on')
            ch = findall(app.figureh,'tag','FigureToolBar');
            UT = get(ch,'children');
            set(UT(1:5),'Visible','Off','Separator','Off')
            set(UT(1),'Separator','On')
            set(UT(8),'Visible','Off','Separator','Off')
            
            %% Close Figure function
            function CloseAppfcn(~,~,app)
                try
                    % Update settings
                    app = updateAppSettings(app);
                    
                    % Save app settings to disk
                    app_settings = app.settings;
                    save(fullfile(app.settings.home,'AppData','Compare_PES_App_Settings.mat'),'app_settings')
                    
                    % Delete temp folder
                    if exist(fullfile(app.settings.home,'AppData','temp'),'dir')
                        try
                            rmdir(fullfile(app.settings.home,'AppData','temp'),'s');
                        catch
                            warning('Unable to delete temporary data files. Permission denied.');
                        end
                    end
                    
                    % Exit app
                    delete(app.figureh)
                    delete(app)
                catch
                    delete(app.figureh)
                    delete(app)
                end
            end
            
            %-------------------------------------------------------------------------%
            % Add crosshair togglebutton to toolbar:
            %-------------------------------------------------------------------------%
            % Read an image
            [cdata,map] = imread([app.settings.home,filesep,'AppData',filesep,'crosshair.gif']);
            
            % Convert white pixels into a transparent background
            map(map(:,1)+map(:,2)+map(:,3)==3) = NaN;
            
            % Convert into 3D RGB-space
            cdata = ind2rgb(cdata,map);
            
            % Set the button icon
            tbh = findall(app.figureh,'Type','uitoolbar');
            app.CrosshairButton = uitoggletool(tbh,'CData',cdata,...
                'Separator','off','Enable','off',...
                'TooltipString','Data Crosshair','OffCallback',@CrosshairOff,...
                'BusyAction','Cancel','tag','Exploration.Crosshair');
            %-------------------------------------------------------------------------%
            % Add Options togglebutton to toolbar:
            %-------------------------------------------------------------------------%
            % Read an image
            [cdata,map] = imread([app.settings.home,filesep,'AppData',filesep,'options.gif']);
            
            % Convert white pixels into a transparent background
            map(map(:,1)+map(:,2)+map(:,3)==3) = NaN;
            
            % Convert into 3D RGB-space
            cdata = ind2rgb(cdata,map);
            
            % Set the button icon
            tbh = findall(app.figureh,'Type','uitoolbar');
            app.MenuButton = uipushtool(tbh,'CData',cdata,...
                'Separator','on','Enable','on','ClickedCallback',{@MenuButtonClicked,app},...
                'TooltipString','Open Options Menu',...
                'BusyAction','Cancel','tag','OptionsFunction');
            %-------------------------------------------------------------------------%
            %-------------------------------------------------------------------------%
            
            drawnow;
            figure(app.PlotOptionsMenuUIFigure);
            
            function MenuButtonClicked(~,~,app)
                if strcmpi(app.PlotOptionsMenuUIFigure.Visible,'off')
                    app.PlotOptionsMenuUIFigure.Visible = 'on';
                end
                drawnow;
                figure(app.PlotOptionsMenuUIFigure);
            end
            
        end

        % Button pushed function: PlotButton
        function ReturnToPlotFcn(app, event)
            
            % Update settings, save, close menu
            app = updateAppSettings(app);
            OptionsMenufcn(app);
            
            % Save app settings to disk
            app_settings = app.settings;
            save(fullfile(app.settings.home,'AppData','Compare_PES_App_Settings.mat'),'app_settings')
            
            % Exit app and run plot
            PlotFail = Compare_PES_Structures(app);
            if PlotFail
                app.CrosshairButton.Enable = 'off';
            end
            
        end

        % Close request function: PlotOptionsMenuUIFigure
        function AppCloseRequest(app, event)
            OptionsMenufcn(app)
        end

        % Callback function
        function PrintTableFcn(app, event)
            % Update settings
            app = updateAppSettings(app);
            
            % Update print table option
            app.settings.PrintTable = true;
            
            % Run the table creator function
            Compare_PES_Structures(app);
            
            % return table option to false
            app.settings.PrintTable = false;
        end

        % Button pushed function: Button
        function DisplayColours(app, event)
            plot_brewer_cmap;
        end

        % Button pushed function: PreviewDataButton
        function PreviewTableFcn(app, event)
            % Update settings
            app = updateAppSettings(app);
            
            % Update print table option
            app.settings.PreviewTable = true;
            app.settings.FormattedTables = false;
            
            % Run the table creator function
            Compare_PES_Structures(app);
            
            % return table option to false
            app.settings.PreviewTable = false;
        end

        % Button pushed function: Button_2
        function Interpolation_Explain(app, event)
            FileName = fullfile(app.settings.home,'AppData','InterpMethods.tif');
            cdata1 = imread(FileName);
            % Make new figure
            figh = figure('Visible','Off');
            axh = axes(figh);
            imshow(cdata1,'Border','tight','Parent',axh);
            set(figh,'MenuBar','none','Name','Interpolation Methods',...
                'NumberTitle','off');
            set(figh,'Visible','On');
            figure(figh);
        end

        % Selection changed function: XAxisUnitsButtonGroup
        function ChangeInterpUnits(app, event)
            selectedButton = app.XAxisUnitsButtonGroup.SelectedObject.Text;
            
            % Update text
            if strcmp(selectedButton,'a') || strcmp(selectedButton,'BL')
                app.Label.Visible = 'Off';
                app.Label_2.Visible = 'Off';
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (Å)';
            elseif strcmp(selectedButton,'V')
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (cm /mol)';
                app.Label.Visible = 'On';
                app.Label_2.Visible = 'Off';
            elseif strcmp(selectedButton,'P')
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (GPa)';
                app.Label.Visible = 'Off';
                app.Label_2.Visible = 'Off';
            elseif strcmp(selectedButton,'Density')
                app.StepSizegcmEditFieldLabel.Text = 'Step Size (g/cm )';
                app.Label.Visible = 'Off';
                app.Label_2.Visible = 'On';
            end
        end

        % Button pushed function: FormattedTablesButton
        function FormattedTablesFcn(app, event)
            % Update settings
            app = updateAppSettings(app);
            
            % Update print table option
            app.settings.PreviewTable = true;
            
            % Update Latex tables option
            app.settings.FormattedTables = true;
            
            % Run the table creator function
            Compare_PES_Structures(app);
            
            % return table options to false
            app.settings.PreviewTable = false;
            app.settings.FormattedTables = false;
        end

        % Value changed function: ScaleEditField
        function ScaleEditFieldValueChanged(app, event)
            ButtonAddScalePushed(app,event);
        end

        % Callback function: PlotOptionsMenuUIFigure, ScaleListBox
        function UIFigureKeyReleaseFcn(app, event)
            if isfield(event,'Key')
                key = event.Key;
            else
                key = '';
            end
            
            switch lower(key)
                case 'delete'
                    RemoveScales(app,event)
                case 'enter'
                    ButtonAddScalePushed(app,event);
            end
        end

        % Button pushed function: ButtonAddScale
        function ButtonAddScalePushed(app, event)
            value = app.ScaleEditField.Value;
            
            % Add item to array
            current_values = cellfun(@str2double,app.ScaleListBox.Items);
            current_values(end+1) = value;
            
            % Sort values
            sorted_values = unique(current_values);
            
            % Return to strings
            valuestxt = cellstr(num2str(sorted_values','%5.5f'))';
            
            app.ScaleListBox.Items = valuestxt;
        end

        % Button pushed function: ButtonRemoveScale
        function RemoveScales(app, event)
            selected = app.ScaleListBox.Value;
            rmidx = ~ismember(app.ScaleListBox.Items,selected);
            app.ScaleListBox.Items = app.ScaleListBox.Items(rmidx);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotOptionsMenuUIFigure = uifigure('Visible', 'off');
            app.PlotOptionsMenuUIFigure.Name = 'Plot Options Menu';
            app.PlotOptionsMenuUIFigure.CloseRequestFcn = createCallbackFcn(app, @AppCloseRequest, true);
            app.PlotOptionsMenuUIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyReleaseFcn, true);
            app.FontSizeEditFieldLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.FontSizeEditFieldLabel.Text = 'Font Size';
            app.FontSizeEditField = uieditfield(app.PlotOptionsMenuUIFigure, 'numeric');
            app.FontSizeEditField.Limits = [0 Inf];
            app.FontSizeEditField.ValueDisplayFormat = '%.0f';
            app.FontSizeEditField.Value = 18;
            app.LineWidthEditFieldLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.LineWidthEditFieldLabel.Text = 'Line Width';
            app.LineWidthEditField = uieditfield(app.PlotOptionsMenuUIFigure, 'numeric');
            app.LineWidthEditField.Limits = [0 Inf];
            app.LineWidthEditField.ValueDisplayFormat = '%.0f';
            app.LineWidthEditField.Value = 3;
            app.XAxisUnitsButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.XAxisUnitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChangeInterpUnits, true);
            app.XAxisUnitsButtonGroup.Title = 'X-Axis Units';
            app.aButton = uitogglebutton(app.XAxisUnitsButtonGroup);
            app.aButton.Text = 'a';
            app.aButton.Value = true;
            app.BLButton = uitogglebutton(app.XAxisUnitsButtonGroup);
            app.BLButton.Text = 'BL';
            app.VButton = uitogglebutton(app.XAxisUnitsButtonGroup);
            app.VButton.Text = 'V';
            app.PButton = uitogglebutton(app.XAxisUnitsButtonGroup);
            app.PButton.Text = 'P';
            app.DensityButton = uitogglebutton(app.XAxisUnitsButtonGroup);
            app.DensityButton.Text = 'Density';
            app.XAxisExtentEditFieldLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.XAxisExtentEditFieldLabel.Text = 'X-Axis Extent (Å)';
            app.XAxisExtentEditField = uieditfield(app.PlotOptionsMenuUIFigure, 'numeric');
            app.XAxisExtentEditField.Limits = [0 Inf];
            app.XAxisExtentEditField.Value = 9;
            app.SaltsPairsListBoxLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.SaltsPairsListBoxLabel.Text = 'Salts/Pairs';
            app.SaltsPairsListBox = uilistbox(app.PlotOptionsMenuUIFigure);
            app.SaltsPairsListBox.Items = {'LiF', 'LiCl', 'LiBr', 'LiI', 'NaCl', 'LiLi', 'FF', 'ClCl', 'BrBr', 'II', 'NaNa'};
            app.SaltsPairsListBox.Multiselect = 'on';
            app.SaltsPairsListBox.Value = {'LiF'};
            app.StructuresListBoxLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.StructuresListBoxLabel.Text = 'Structures';
            app.StructuresListBox = uilistbox(app.PlotOptionsMenuUIFigure);
            app.StructuresListBox.Items = {'Rocksalt', 'Wurtzite', 'Sphalerite', 'CsCl', 'NiAs', 'BetaBeO', 'FiveFive', 'Pair'};
            app.StructuresListBox.Multiselect = 'on';
            app.StructuresListBox.Value = {'Rocksalt'};
            app.SecondaryAxisButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.SecondaryAxisButtonGroup.Title = 'Secondary Axis';
            app.NoPlotButton = uitogglebutton(app.SecondaryAxisButtonGroup);
            app.NoPlotButton.Text = 'No Plot';
            app.NoPlotButton.Value = true;
            app.CPUTimePlotButton = uitogglebutton(app.SecondaryAxisButtonGroup);
            app.CPUTimePlotButton.Text = 'CPU Time Plot';
            app.BSSEPlotButton = uitogglebutton(app.SecondaryAxisButtonGroup);
            app.BSSEPlotButton.Text = 'BSSE Plot';
            app.XAxisAlignmentButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.XAxisAlignmentButtonGroup.Title = 'X-Axis Alignment';
            app.XButton = uitogglebutton(app.XAxisAlignmentButtonGroup);
            app.XButton.Text = 'X';
            app.XButton.Value = true;
            app.XXminButton = uitogglebutton(app.XAxisAlignmentButtonGroup);
            app.XXminButton.Text = 'X - Xmin';
            app.YAxisAlignmentButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.YAxisAlignmentButtonGroup.Title = 'Y-Axis Alignment';
            app.YButton = uitogglebutton(app.YAxisAlignmentButtonGroup);
            app.YButton.Text = 'Y';
            app.YButton.Value = true;
            app.YYminButton = uitogglebutton(app.YAxisAlignmentButtonGroup);
            app.YYminButton.Text = 'Y - Ymin';
            app.InterpolationButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.InterpolationButtonGroup.Title = 'Interpolation';
            app.Switch = uiswitch(app.InterpolationButtonGroup, 'slider');
            app.DropDown = uidropdown(app.InterpolationButtonGroup);
            app.DropDown.Items = {'Linear', 'Nearest', 'Next', 'Previous', 'PCHIP', 'V5Cubic', 'Mod Akima', 'Spline'};
            app.DropDown.Value = 'Spline';
            app.Button_2 = uibutton(app.InterpolationButtonGroup, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Interpolation_Explain, true);
            app.Button_2.Text = '?';
            app.StepSizegcmEditFieldLabel = uilabel(app.InterpolationButtonGroup);
            app.StepSizegcmEditFieldLabel.Text = 'Step Size (g/cm )';
            app.StepSizegcmEditField = uieditfield(app.InterpolationButtonGroup, 'numeric');
            app.StepSizegcmEditField.Limits = [1e-10 0.1];
            app.StepSizegcmEditField.Value = 0.001;
            app.Label = uilabel(app.InterpolationButtonGroup);
            app.Label.Text = '     3';
            app.Label_2 = uilabel(app.InterpolationButtonGroup);
            app.Label_2.Text = '3';
            app.PrimaryAxisButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.PrimaryAxisButtonGroup.Title = 'Primary Axis';
            app.LinePlotButton = uitogglebutton(app.PrimaryAxisButtonGroup);
            app.LinePlotButton.Text = 'Line Plot';
            app.LinePlotButton.Value = true;
            app.ScatterPlotButton = uitogglebutton(app.PrimaryAxisButtonGroup);
            app.ScatterPlotButton.Text = 'Scatter Plot';
            app.BarPlotButton = uitogglebutton(app.PrimaryAxisButtonGroup);
            app.BarPlotButton.Text = 'Bar Plot';
            app.PlotButton = uibutton(app.PlotOptionsMenuUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnToPlotFcn, true);
            app.PlotButton.Text = 'Plot';
            app.YAxisUnitsButtonGroup_2 = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.YAxisUnitsButtonGroup_2.Title = 'Y-Axis Units';
            app.LatticeEnergyButton = uitogglebutton(app.YAxisUnitsButtonGroup_2);
            app.LatticeEnergyButton.Text = 'Lattice Energy';
            app.LatticeEnergyButton.Value = true;
            app.CohesiveEnergyButton = uitogglebutton(app.YAxisUnitsButtonGroup_2);
            app.CohesiveEnergyButton.Text = 'Cohesive Energy';
            app.PressureButton_2 = uitogglebutton(app.YAxisUnitsButtonGroup_2);
            app.PressureButton_2.Text = 'Pressure';
            app.ColourSchemeDropDownLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.ColourSchemeDropDownLabel.Text = 'Colour Scheme';
            app.ColourSchemeDropDown = uidropdown(app.PlotOptionsMenuUIFigure);
            app.ColourSchemeDropDown.Items = {'Set1', 'Set2', 'Set3', 'Accent', 'Dark2', 'Paired', 'Pastel1', 'Pastel2', 'YlOrRed', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Spectral', 'RdYlGn', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG'};
            app.ColourSchemeDropDown.Value = 'Set1';
            app.TheoriesListBoxLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.TheoriesListBoxLabel.Text = 'Theories';
            app.TheoriesListBox = uilistbox(app.PlotOptionsMenuUIFigure);
            app.TheoriesListBox.Items = {'LSDA', 'HF', 'HF-D3', 'HF-D3-gCP', 'HF-D4', 'HF-D4TB', 'PBE', 'PBE-D3', 'PBE-D3-gCP', 'PBE-D4', 'PBE-D4TB', 'PW1PW', 'PW1PW-D3', 'PW1PW-D3-gCP', 'PW1PW-D4', 'PW1PW-D4TB', 'HSE06', 'HSE06-D3', 'HSE06-D3-gCP', 'HSEsol', 'HSEsol-D3', 'HSEsol-D3-gCP', 'M06L', 'M06', 'M06-D3', 'M06-D3-gCP', 'M06-D4', 'M06-D4TB', 'M062X', 'B3LYP', 'B3LYP-D3', 'B3LYP-D4', 'B3LYP-D4TB', 'B3LYP-D3-gCP', 'JC', 'JC3P', 'JC4P', 'TF', 'TF-D3', 'TF-D4', 'TF-Update'};
            app.TheoriesListBox.Multiselect = 'on';
            app.TheoriesListBox.Value = {'LSDA'};
            app.FileNameEditFieldLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.PlotOptionsMenuUIFigure, 'text');
            app.FileNameEditField.Value = 'Data_Table';
            app.DataTypesListBoxLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.DataTypesListBoxLabel.Text = 'Data Types';
            app.DataTypesListBox = uilistbox(app.PlotOptionsMenuUIFigure);
            app.DataTypesListBox.Items = {'Rigid Structure', 'Cell Opt', 'Full Opt', 'Full Opt (No Sym)', 'Full Opt (P = 1 atm)', 'Atom Optimized'};
            app.DataTypesListBox.Multiselect = 'on';
            app.DataTypesListBox.Value = {'Rigid Structure'};
            app.AutoPlotButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.AutoPlotButtonGroup.Title = 'Auto Plot';
            app.EnableButton = uitogglebutton(app.AutoPlotButtonGroup);
            app.EnableButton.Text = 'Enable';
            app.EnableButton.Value = true;
            app.DisableButton = uitogglebutton(app.AutoPlotButtonGroup);
            app.DisableButton.Text = 'Disable';
            app.Button = uibutton(app.PlotOptionsMenuUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @DisplayColours, true);
            app.Button.Text = '?';
            app.PreviewDataButton = uibutton(app.PlotOptionsMenuUIFigure, 'push');
            app.PreviewDataButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewTableFcn, true);
            app.PreviewDataButton.Text = 'Preview Data';
            app.ExperimentalDataButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.ExperimentalDataButtonGroup.Title = 'Experimental Data';
            app.NotincludedButton_2 = uitogglebutton(app.ExperimentalDataButtonGroup);
            app.NotincludedButton_2.Text = 'Not included';
            app.NotincludedButton_2.Value = true;
            app.IncludedButton_2 = uitogglebutton(app.ExperimentalDataButtonGroup);
            app.IncludedButton_2.Text = 'Included';
            app.BSSECorrectionButtonGroup = uibuttongroup(app.PlotOptionsMenuUIFigure);
            app.BSSECorrectionButtonGroup.Title = 'BSSE Correction';
            app.NoCorrectionButton = uitogglebutton(app.BSSECorrectionButtonGroup);
            app.NoCorrectionButton.Text = 'No Correction';
            app.NoCorrectionButton.Value = true;
            app.CorrectionButton = uitogglebutton(app.BSSECorrectionButtonGroup);
            app.CorrectionButton.Text = 'Correction';
            app.BasisSetListBoxLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.BasisSetListBoxLabel.Text = 'Basis Set';
            app.BasisSetListBox = uilistbox(app.PlotOptionsMenuUIFigure);
            app.BasisSetListBox.Items = {'pob-TZVP', 'def2-TZVPD', 'CRYSTAL Old Basis', 'STO-3G', 'Mpob-TZVPP_Hpob-TZVP', 'Mpob-TZVP-rev2_Hpob-TZVP', 'Mpob-TZVP_HECP28MDF-VTZ', 'Mdef2-TZVPD_HECP28MDF-AVTZ', 'Mdef2-TZVPD_HECP28MDF-AV5Z', 'ECP28MDF-AVTZ', 'ECP28MDF-AV5Z'};
            app.BasisSetListBox.Multiselect = 'on';
            app.BasisSetListBox.Value = {'pob-TZVP'};
            app.QMOnlyLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.QMOnlyLabel.Text = '(QM Only)';
            app.FormattedTablesButton = uibutton(app.PlotOptionsMenuUIFigure, 'push');
            app.FormattedTablesButton.ButtonPushedFcn = createCallbackFcn(app, @FormattedTablesFcn, true);
            app.FormattedTablesButton.Text = 'Formatted Tables';
            app.ScaleEmpiricalListBoxLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.ScaleEmpiricalListBoxLabel.Text = 'Scale Empirical';
            app.ScaleEmpiricalListBox = uilistbox(app.PlotOptionsMenuUIFigure);
            app.ScaleEmpiricalListBox.Items = {'Disp', 'Rep', 'Epsilon', 'Sigma', 'MMD', 'XXD', 'MXD'};
            app.ScaleEmpiricalListBox.Value = 'Disp';
            app.ScaleEditField = uieditfield(app.PlotOptionsMenuUIFigure, 'numeric');
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleEditFieldValueChanged, true);
            app.ScaleEditField.Value = 1;
            app.CurrentScalesPanel = uipanel(app.PlotOptionsMenuUIFigure);
            app.CurrentScalesPanel.Title = 'Current Scales';
            app.ScaleListBox = uilistbox(app.CurrentScalesPanel);
            app.ScaleListBox.Items = {'1.00000'};
            app.ScaleListBox.Multiselect = 'on';
            app.ScaleListBox.ValueChangedFcn = createCallbackFcn(app, @UIFigureKeyReleaseFcn, true);
            app.ScaleListBox.Value = {};
            app.ButtonRemoveScale = uibutton(app.CurrentScalesPanel, 'push');
            app.ButtonRemoveScale.ButtonPushedFcn = createCallbackFcn(app, @RemoveScales, true);
            app.ButtonRemoveScale.Icon = 'minus.png';
            app.ButtonRemoveScale.Text = '';
            app.ButtonAddScale = uibutton(app.PlotOptionsMenuUIFigure, 'push');
            app.ButtonAddScale.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddScalePushed, true);
            app.ButtonAddScale.Icon = 'plus.png';
            app.ButtonAddScale.Text = '';
            app.EmpDampingListBoxLabel = uilabel(app.PlotOptionsMenuUIFigure);
            app.EmpDampingListBoxLabel.Text = 'Emp. Damping';
            app.EmpDampingListBox = uilistbox(app.PlotOptionsMenuUIFigure);
            app.EmpDampingListBox.Items = {'No Damping', 'BJ Damping', 'Tang Damping', 'MMDRE Damping', 'MANoC Damping', 'EHFSK Damping', 'WY Damping'};
            app.EmpDampingListBox.Multiselect = 'on';
            app.EmpDampingListBox.Value = {'No Damping'};
            % Show the figure after all components are created
            app.PlotOptionsMenuUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = compare_energy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotOptionsMenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotOptionsMenuUIFigure)
        end
    end
end